classdef exampleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageEnhancementUIFigure  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        EnterImageTab             matlab.ui.container.Tab
        SelectanimageandenterfourpointsLabel  matlab.ui.control.Label
        BrowseButton              matlab.ui.control.Button
        Panel_5                   matlab.ui.container.Panel
        OriginalImage             matlab.ui.control.Image
        Panel_3                   matlab.ui.container.Panel
        EnchanceImageButton       matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        x1TextArea                matlab.ui.control.TextArea
        x1TextAreaLabel           matlab.ui.control.Label
        x2TextArea                matlab.ui.control.TextArea
        x2TextAreaLabel           matlab.ui.control.Label
        Panel_4                   matlab.ui.container.Panel
        y2TextArea                matlab.ui.control.TextArea
        y2TextAreaLabel           matlab.ui.control.Label
        y1TextArea                matlab.ui.control.TextArea
        y1TextAreaLabel           matlab.ui.control.Label
        EnhancedImageTab          matlab.ui.container.Tab
        SaveImageButton           matlab.ui.control.Button
        Panel_6                   matlab.ui.container.Panel
        bicubicImage              matlab.ui.control.Image
        VDSRImage                 matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnchanceImageButton
        function EnchanceImageButtonPushed(app, event)
            x1 = str2double(app.x1TextArea.Value(1));
            x2 = str2double(app.x2TextArea.Value(1));
            y1 = str2double(app.y1TextArea.Value(1));
            y2 = str2double(app.y2TextArea.Value(1));
            image = app.OriginalImage.ImageSource;
            image = imread(image);
            newImage = imcrop(image, [x1, y1, x2, y2]);
            newImage = im2double(newImage);


            [nrows,ncols,np] = size(newImage);
            bicubicImg = imresize(newImage,[nrows ncols],"bicubic");
            Iycbcr = rgb2ycbcr(newImage);
            Iy = Iycbcr(:,:,1);
            Icb = Iycbcr(:,:,2);
            Icr = Iycbcr(:,:,3);

            Iy_bicubic = imresize(Iy,[nrows ncols],"bicubic");
            Icb_bicubic = imresize(Icb,[nrows ncols],"bicubic");
            Icr_bicubic = imresize(Icr,[nrows ncols],"bicubic");
            load('trainedVDSR-2024-04-14-18-28-12.mat', 'net');
            Iresidual = predict(net,Iy_bicubic);
            Iresidual = double(Iresidual);

            Isr = Iy_bicubic + Iresidual;
            Ivdsr = ycbcr2rgb(cat(3,Isr,Icb_bicubic,Icr_bicubic));

            app.bicubicImage.ImageSource = bicubicImg;
            app.VDSRImage.ImageSource = Ivdsr;

            app.TabGroup.SelectedTab = app.EnhancedImageTab;

            bicubicPSNR = psnr(bicubicImg,newImage)
            vdsrPSNR = psnr(Ivdsr,newImage)
            bicubicSSIM = ssim(bicubicImg,newImage)
            vdsrSSIM = ssim(Ivdsr,newImage)
            bicubicNIQE = niqe(bicubicImg)
            vdsrNIQE = niqe(Ivdsr)
        end

        % Callback function
        function ImageNameTextAreaValueChanged(app, event)

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            file = uigetfile({'*.jpg';'*.png'; '*.jpeg'});
            app.OriginalImage.ImageSource = file;
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            savedfile = uiputfile({'*.png', '*.jpg'}, 'File Selection', 'enhancedImage.jpg');
            imwrite(app.VDSRImage.ImageSource, savedfile);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ImageEnhancementUIFigure = uifigure('Visible', 'off');
            app.ImageEnhancementUIFigure.Name = 'Image Enhancement';
            app.ImageEnhancementUIFigure.Icon = fullfile(pathToMLAPP, 'cameraicon.png');
            app.TabGroup = uitabgroup(app.ImageEnhancementUIFigure);
            app.EnterImageTab = uitab(app.TabGroup);
            app.EnterImageTab.Title = 'Enter Image';
            app.Panel_3 = uipanel(app.EnterImageTab);
            app.Panel_4 = uipanel(app.Panel_3);
            app.y1TextAreaLabel = uilabel(app.Panel_4);
            app.y1TextAreaLabel.Text = 'y1';
            app.y1TextArea = uitextarea(app.Panel_4);
            app.y2TextAreaLabel = uilabel(app.Panel_4);
            app.y2TextAreaLabel.Text = 'y2';
            app.y2TextArea = uitextarea(app.Panel_4);
            app.Panel_2 = uipanel(app.Panel_3);
            app.x2TextAreaLabel = uilabel(app.Panel_2);
            app.x2TextAreaLabel.Text = 'x2';
            app.x2TextArea = uitextarea(app.Panel_2);
            app.x1TextAreaLabel = uilabel(app.Panel_2);
            app.x1TextAreaLabel.Text = 'x1';
            app.x1TextArea = uitextarea(app.Panel_2);
            app.EnchanceImageButton = uibutton(app.Panel_3, 'push');
            app.EnchanceImageButton.ButtonPushedFcn = createCallbackFcn(app, @EnchanceImageButtonPushed, true);
            app.EnchanceImageButton.Text = 'Enchance Image';
            app.Panel_5 = uipanel(app.EnterImageTab);
            app.OriginalImage = uiimage(app.Panel_5);
            app.OriginalImage.ImageSource = fullfile(pathToMLAPP, 'tree.jpg');
            app.BrowseButton = uibutton(app.EnterImageTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SelectanimageandenterfourpointsLabel = uilabel(app.EnterImageTab);
            app.SelectanimageandenterfourpointsLabel.Text = {'Select an image and enter'; ' four coordinatesfor which'; ' you want to zoom in.'};
            app.EnhancedImageTab = uitab(app.TabGroup);
            app.EnhancedImageTab.Title = 'Enhanced Image';
            app.Panel_6 = uipanel(app.EnhancedImageTab);
            app.VDSRImage = uiimage(app.Panel_6);
            app.VDSRImage.ImageSource = fullfile(pathToMLAPP, 'box.png');
            app.bicubicImage = uiimage(app.Panel_6);
            app.bicubicImage.ImageSource = fullfile(pathToMLAPP, 'box.png');
            app.SaveImageButton = uibutton(app.EnhancedImageTab, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            % Show the figure after all components are created
            app.ImageEnhancementUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exampleApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageEnhancementUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageEnhancementUIFigure)
        end
    end
end